classdef DRAGON < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        ClearAllMenu                    matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        UndoMenu                        matlab.ui.container.Menu
        RedoMenu                        matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        WindowedMenu                    matlab.ui.container.Menu
        MaximizedMenu                   matlab.ui.container.Menu
        FullScreenMenu                  matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        WorkspaceLabel                  matlab.ui.control.Label
        LogLabel                        matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        ODF_Panel                       matlab.ui.container.Panel
        ResolutiondegreeEditField       matlab.ui.control.NumericEditField
        ResolutiondegreeEditFieldLabel  matlab.ui.control.Label
        KernelDropDown                  matlab.ui.control.DropDown
        KernelDropDownLabel             matlab.ui.control.Label
        LatticeTypeDropDown             matlab.ui.control.DropDown
        LatticeTypeDropDownLabel        matlab.ui.control.Label
        ProjectionDropDown              matlab.ui.control.DropDown
        ProjectionDropDownLabel         matlab.ui.control.Label
        PF_Panel                        matlab.ui.container.Panel
        LoadImageButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Remove_Smart                    matlab.ui.control.Button
        Add_Smart                       matlab.ui.control.Button
        WS                              matlab.ui.container.CheckBoxTree
        Dorkus                          matlab.ui.control.Image
    end


    properties (Access = private)
        % Description
    end

    properties (Access = public)
        selectedNode;
        checkedNodes;
    end

    methods (Access = public)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~(ismcc || isdeployed)
                [~, p] = getFoldersToAddToPath(getAppRoot);
                cellfun(@(x) addpath(x), p);
                fprintf('%s\n', '...Added');
            end
        end

        % Menu selected function: FullScreenMenu
        function FullScreenMenuSelected(app, event)
            app.UIFigure.WindowState = 'fullscreen';
        end

        % Menu selected function: MaximizedMenu
        function MaximizedMenuSelected(app, event)
            app.UIFigure.WindowState = 'maximized';
        end

        % Menu selected function: WindowedMenu
        function WindowedMenuSelected(app, event)
            app.UIFigure.WindowState = 'normal';
        end

        % Button pushed function: Add_Smart
        function Add_SmartButtonPushed(app, event)

            if ~isempty(app.selectedNode)

                switch app.selectedNode.Tag
                    case 'ODF'
                        newPF(app, Parent=app.selectedNode);
                    case 'PF'
                        newPF(app, Parent=app.selectedNode.Parent);
                    otherwise
                        newODF(app, Parent=app.WS);
                end

            else
                newODF(app, Parent=app.WS);
            end
        end

        % Callback function: WS
        function WSCheckedNodesChanged(app, event)
            app.checkedNodes = app.WS.CheckedNodes;
        end

        % Selection changed function: WS
        function WSSelectionChanged(app, event)
            app.selectedNode = app.WS.SelectedNodes;
            % TODO: Ctrl+Clicking Deselects, and that doesn't work with this code.

            if ~isempty(app.selectedNode)
                if strcmp(app.selectedNode.Tag,'ODF')
                    app.ODF_Panel.Visible = 'on';
                    app.PF_Panel.Visible = 'off';
                elseif strcmp(app.selectedNode.Tag,'PF')
                    app.ODF_Panel.Visible = 'off';
                    app.PF_Panel.Visible = 'on';
                end
            else
                app.ODF_Panel.Visible = 'off';
                app.PF_Panel.Visible = 'off';
            end
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)

        end

        % Button pushed function: Remove_Smart
        function Remove_SmartButtonPushed(app, event)

            if ~isempty(app.checkedNodes)
                delete(app.checkedNodes)
                app.checkedNodes = [];
                app.selectedNode = [];
            end

            if ~isempty(app.selectedNode)

                if strcmp(app.selectedNode.Parent.Tag,'ODF')
                    app.ODF_Panel.Visible = 'on';
                    app.PF_Panel.Visible = 'off';
                elseif strcmp(app.selectedNode.Parent.Tag,'PF')
                    app.ODF_Panel.Visible = 'off';
                    app.PF_Panel.Visible = 'on';
                else
                    app.ODF_Panel.Visible = 'off';
                    app.PF_Panel.Visible = 'off';
                end

                delete(app.selectedNode);

                app.selectedNode = [];

            end

        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            newODF(app, Parent=app.WS);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = 'New';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Accelerator = 'O';
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.ClearAllMenu = uimenu(app.FileMenu);
            app.ClearAllMenu.Text = ' Clear All';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.UndoMenu = uimenu(app.EditMenu);
            app.UndoMenu.Accelerator = 'Z';
            app.UndoMenu.Text = 'Undo';
            app.RedoMenu = uimenu(app.EditMenu);
            app.RedoMenu.Accelerator = 'Y';
            app.RedoMenu.Text = 'Redo';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.WindowedMenu = uimenu(app.ViewMenu);
            app.WindowedMenu.MenuSelectedFcn = createCallbackFcn(app, @WindowedMenuSelected, true);
            app.WindowedMenu.Text = 'Windowed';
            app.MaximizedMenu = uimenu(app.ViewMenu);
            app.MaximizedMenu.MenuSelectedFcn = createCallbackFcn(app, @MaximizedMenuSelected, true);
            app.MaximizedMenu.Text = 'Maximized';
            app.FullScreenMenu = uimenu(app.ViewMenu);
            app.FullScreenMenu.MenuSelectedFcn = createCallbackFcn(app, @FullScreenMenuSelected, true);
            app.FullScreenMenu.Text = 'Full Screen';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.Dorkus = uiimage(app.UIFigure);
            app.Dorkus.ImageSource = 'Dorkus.png';
            app.WS = uitree(app.UIFigure, 'checkbox');
            app.WS.SelectionChangedFcn = createCallbackFcn(app, @WSSelectionChanged, true);
            % Assign Checked Nodes
            app.WS.CheckedNodesChangedFcn = createCallbackFcn(app, @WSCheckedNodesChanged, true);
            app.Add_Smart = uibutton(app.UIFigure, 'push');
            app.Add_Smart.ButtonPushedFcn = createCallbackFcn(app, @Add_SmartButtonPushed, true);
            app.Add_Smart.Text = '+';
            app.Remove_Smart = uibutton(app.UIFigure, 'push');
            app.Remove_Smart.ButtonPushedFcn = createCallbackFcn(app, @Remove_SmartButtonPushed, true);
            app.Remove_Smart.Text = '-';
            app.PF_Panel = uipanel(app.UIFigure);
            app.PF_Panel.Visible = 'off';
            app.UIAxes = uiaxes(app.PF_Panel);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadImageButton = uibutton(app.PF_Panel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ODF_Panel = uipanel(app.UIFigure);
            app.ODF_Panel.Visible = 'off';
            app.ProjectionDropDownLabel = uilabel(app.ODF_Panel);
            app.ProjectionDropDownLabel.Text = 'Projection';
            app.ProjectionDropDown = uidropdown(app.ODF_Panel);
            app.ProjectionDropDown.Items = {'Equal Angle', 'Equal Area', 'Equal Distance', ''};
            app.ProjectionDropDown.Tooltip = {'Only option...for now.'};
            app.ProjectionDropDown.Value = 'Equal Area';
            app.LatticeTypeDropDownLabel = uilabel(app.ODF_Panel);
            app.LatticeTypeDropDownLabel.Text = 'Lattice Type';
            app.LatticeTypeDropDown = uidropdown(app.ODF_Panel);
            app.LatticeTypeDropDown.Items = {'cubic', 'hexagonal', 'tetragonal', 'trigonal', 'orthorhombic', 'monoclinic', 'triclinic'};
            app.LatticeTypeDropDown.Tooltip = {'Set the crystal symmetry of the pole figure. Add additional through the Symmetry menu or the config file.'};
            app.LatticeTypeDropDown.Value = 'cubic';
            app.KernelDropDownLabel = uilabel(app.ODF_Panel);
            app.KernelDropDownLabel.Text = 'Kernel';
            app.KernelDropDown = uidropdown(app.ODF_Panel);
            app.KernelDropDown.Items = {'deLaValeePoussin', ''};
            app.KernelDropDown.Tooltip = {'Only option...for now.'};
            app.KernelDropDown.Value = 'deLaValeePoussin';
            app.ResolutiondegreeEditFieldLabel = uilabel(app.ODF_Panel);
            app.ResolutiondegreeEditFieldLabel.Text = 'Resolution (degree)';
            app.ResolutiondegreeEditField = uieditfield(app.ODF_Panel, 'numeric');
            app.ResolutiondegreeEditField.Tooltip = {'Halfwidth for deLaValeePoussin kernel (in degrees). Default is 10.'};
            app.ResolutiondegreeEditField.Value = 10;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.LogLabel = uilabel(app.UIFigure);
            app.LogLabel.Text = 'Log:';
            app.WorkspaceLabel = uilabel(app.UIFigure);
            app.WorkspaceLabel.Text = 'Workspace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DRAGON

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end